classdef collectionfactors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        RatioSlider       matlab.ui.control.Slider
        RatioSliderLabel  matlab.ui.control.Label
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updatePlots(app)
            Phi = -3:0.001:5;
            epsilon = app.RatioSlider.Value;
            F_sp = spherical(app,Phi,epsilon);
            F_c = cylindrical(app,Phi,epsilon);

            plot(app.UIAxes,Phi,F_c,'LineWidth',2)
            plot(app.UIAxes_2,Phi,F_sp,'LineWidth',2)
            drawnow

        end
        
        function F_sp = spherical(app,Phi,epsilon) 
            Phi_t = sqrt(Phi./(epsilon.^2-1)); % phi tilde
            F_sp(Phi <= 0) = exp(Phi(Phi <= 0));
            F_sp(Phi > 0) = epsilon.^2*(1-exp(-1*Phi_t(Phi > 0).^2)) + ...
                exp(-1*Phi_t(Phi > 0).^2);
        end

        function F_c = cylindrical(app,Phi,epsilon) 
            Phi_t = sqrt(Phi./(epsilon.^2-1)); % phi tilde
            F_c(Phi <= 0) = exp(Phi(Phi <= 0));
            F_c(Phi > 0) = epsilon*erf(Phi_t(Phi > 0)) + ...
                exp(Phi(Phi > 0)).*erfc(epsilon.*Phi_t(Phi > 0));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RatioSliderLabel.Text = sprintf('Ratio: %1.2f',app.RatioSlider.Value);
            updatePlots(app)
        end

        % Value changed function: RatioSlider
        function RatioSliderValueChanged(app, event)
            value = app.RatioSlider.Value;
            app.RatioSliderLabel.Text = sprintf('Ratio: %1.2f',value);
            updatePlots(app)
        end

        % Value changing function: RatioSlider
        function RatioSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RatioSliderLabel.Text = sprintf('Ratio: %1.2f',changingValue);
            updatePlots(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cylindrical')
            xlabel(app.UIAxes, '\Phi_s, Normalized Sheath Potential (unitless)')
            ylabel(app.UIAxes, 'F, Collection Factor (unitless)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-3 5];
            app.UIAxes.YLim = [0 3];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Spherical')
            xlabel(app.UIAxes_2, '\Phi_s, Normalized Sheath Potential (unitless)')
            ylabel(app.UIAxes_2, 'F, Collection Factor (unitless)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [-3 5];
            app.UIAxes_2.YLim = [0 3];
            app.RatioSliderLabel = uilabel(app.UIFigure);
            app.RatioSliderLabel.Text = 'Ratio';
            app.RatioSlider = uislider(app.UIFigure);
            app.RatioSlider.Limits = [1 5];
            app.RatioSlider.MajorTicks = [1 2 3 4 5];
            app.RatioSlider.ValueChangedFcn = createCallbackFcn(app, @RatioSliderValueChanged, true);
            app.RatioSlider.ValueChangingFcn = createCallbackFcn(app, @RatioSliderValueChanging, true);
            app.RatioSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = collectionfactors

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end